classdef signal_rlc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        DropDown                        matlab.ui.control.DropDown
        RSpinnerLabel                   matlab.ui.control.Label
        RSpinner                        matlab.ui.control.Spinner
        LSpinnerLabel                   matlab.ui.control.Label
        LSpinner                        matlab.ui.control.Spinner
        CSpinnerLabel                   matlab.ui.control.Label
        CSpinner                        matlab.ui.control.Spinner
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        signalDropDownLabel             matlab.ui.control.Label
        signalDropDown                  matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        VoutDropDownLabel               matlab.ui.control.Label
        VoutDropDown                    matlab.ui.control.DropDown
        AngularresonanceEditFieldLabel  matlab.ui.control.Label
        AngularresonanceEditField       matlab.ui.control.EditField
        propertiesLabel                 matlab.ui.control.Label
        phasemarginEditFieldLabel       matlab.ui.control.Label
        phasemarginEditField            matlab.ui.control.EditField
        radsLabel                       matlab.ui.control.Label
        OLabel                          matlab.ui.control.Label
        dampingfactorEditFieldLabel     matlab.ui.control.Label
        dampingfactorEditField          matlab.ui.control.EditField
        startdisplayEditFieldLabel      matlab.ui.control.Label
        startdisplayEditField           matlab.ui.control.EditField
        enddisplayLabel                 matlab.ui.control.Label
        enddisplayEditField             matlab.ui.control.EditField
        okButton                        matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            circuit = app.DropDown_4.Value;
            r_unit = app.DropDown;
            l_unit = app.DropDown_2;
            c_unit = app.DropDown_3;
            signal = app.signalDropDown;
            
            if r_unit.Value == "mΩ"
               r = app.RSpinner.Value*10^-3;
            elseif r_unit.Value == "kΩ"
               r = app.RSpinner.Value*10^3;
               
            elseif r_unit.Value == "Ω"
               r = app.RSpinner.Value;
            end
            
            if l_unit.Value == "mH"
               l = app.LSpinner.Value*10^-3;
            elseif l_unit.Value == "uH"
               l = app.LSpinner.Value*10^-6;
            elseif l_unit.Value == "nH"
               l = app.LSpinner.Value*10^-9;
            elseif l_unit.Value == "H"
               l = app.LSpinner.Value;
            end
            
            if c_unit.Value == "F"
               c = app.CSpinner.Value;
            elseif c_unit.Value == "uF"
               c = app.CSpinner.Value*10^-6;
            elseif c_unit.Value == "nF"
               c = app.CSpinner.Value*10^-9;
            elseif c_unit.Value == "pF"
               c = app.CSpinner.Value*10^-12;
            end
            
            if circuit == "อนุกรม"
                damp = r/(2*l);
                w = 1/sqrt(l*c);
                check_case(app,damp,w);
            elseif circuit == "ขนาน"
                damp = 1/(2*r*c);
                w = 1/sqrt(l*c);
                check_case(app,damp,w);
            end
            
           if signal == "sine"
               f=500; %frequency [Hz]
               t=(0:1/(f*100):0.01);
               a=1;    %amplitude [V]
               y=a*sin(2*pi*f*t);
               plot(t,y)
               xlabel('time(s)')
               ylabel('Voltage(V)') 
            elseif circuit == "ramp"
                
           end
           
        function check_case(app,damp,w)
            if w < damp
                app.dampingfactorEditField.Value = "overdamped";
                s_1 = -damp+sqrt((damp)^2-(w)^2)
                s_2 = -damp-sqrt((damp)^2-(w)^2)
                v = (A*exp(s_1*t))
            elseif w == damp
                app.dampingfactorEditField.Value = "critical damped";
            elseif w > damp
                app.dampingfactorEditField.Value = "underdamped";
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            zlabel(app.UIAxes2_3, 'Z')
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Ω', 'mΩ', 'kΩ', ''};
            app.DropDown.Value = 'Ω';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.LSpinnerLabel = uilabel(app.UIFigure);
            app.LSpinnerLabel.Text = 'L';
            app.LSpinner = uispinner(app.UIFigure);
            app.CSpinnerLabel = uilabel(app.UIFigure);
            app.CSpinnerLabel.Text = 'C';
            app.CSpinner = uispinner(app.UIFigure);
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'mH', 'uH', 'nH', 'H'};
            app.DropDown_2.Value = 'H';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'uF', 'F', 'nF', 'pF'};
            app.DropDown_3.Value = 'F';
            app.signalDropDownLabel = uilabel(app.UIFigure);
            app.signalDropDownLabel.Text = 'signal';
            app.signalDropDown = uidropdown(app.UIFigure);
            app.signalDropDown.Items = {'ramp', 'sine', 'pulse'};
            app.signalDropDown.Value = 'ramp';
            app.DropDown_4Label = uilabel(app.UIFigure);
            app.DropDown_4Label.Text = 'วงจร';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'อนุกรม', 'ขนาน', ''};
            app.DropDown_4.Value = 'อนุกรม';
            app.VoutDropDownLabel = uilabel(app.UIFigure);
            app.VoutDropDownLabel.Text = 'Vout';
            app.VoutDropDown = uidropdown(app.UIFigure);
            app.VoutDropDown.Items = {'R', 'L', 'C'};
            app.VoutDropDown.Value = 'R';
            app.AngularresonanceEditFieldLabel = uilabel(app.UIFigure);
            app.AngularresonanceEditFieldLabel.Text = 'Angular resonance';
            app.AngularresonanceEditField = uieditfield(app.UIFigure, 'text');
            app.propertiesLabel = uilabel(app.UIFigure);
            app.propertiesLabel.Text = 'properties';
            app.phasemarginEditFieldLabel = uilabel(app.UIFigure);
            app.phasemarginEditFieldLabel.Text = 'phase margin';
            app.phasemarginEditField = uieditfield(app.UIFigure, 'text');
            app.radsLabel = uilabel(app.UIFigure);
            app.radsLabel.Text = 'rad/s';
            app.OLabel = uilabel(app.UIFigure);
            app.OLabel.Text = 'O';
            app.dampingfactorEditFieldLabel = uilabel(app.UIFigure);
            app.dampingfactorEditFieldLabel.Text = 'damping factor';
            app.dampingfactorEditField = uieditfield(app.UIFigure, 'text');
            app.startdisplayEditFieldLabel = uilabel(app.UIFigure);
            app.startdisplayEditFieldLabel.Text = 'start display';
            app.startdisplayEditField = uieditfield(app.UIFigure, 'text');
            app.enddisplayLabel = uilabel(app.UIFigure);
            app.enddisplayLabel.Text = 'end display';
            app.enddisplayEditField = uieditfield(app.UIFigure, 'text');
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_rlc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end