classdef signal_rlc1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2_3                   matlab.ui.control.UIAxes
        UIAxes2_4                   matlab.ui.control.UIAxes
        DropDown                    matlab.ui.control.DropDown
        DropDown_2                  matlab.ui.control.DropDown
        DropDown_3                  matlab.ui.control.DropDown
        OKButton                    matlab.ui.control.Button
        DropDown_5                  matlab.ui.control.DropDown
        VALabel                     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        BodeDiagramLabel            matlab.ui.control.Label
        OutputWaveformVoltageCurrentLabel  matlab.ui.control.Label
        VoltageLabel                matlab.ui.control.Label
        CurrentLabel                matlab.ui.control.Label
        RSpinnerLabel               matlab.ui.control.Label
        RSpinner                    matlab.ui.control.Spinner
        LSpinnerLabel               matlab.ui.control.Label
        LSpinner                    matlab.ui.control.Spinner
        CSpinnerLabel               matlab.ui.control.Label
        CSpinner                    matlab.ui.control.Spinner
        signalDropDownLabel         matlab.ui.control.Label
        signalDropDown              matlab.ui.control.DropDown
        CurcuitDropDownLabel        matlab.ui.control.Label
        CurcuitDropDown             matlab.ui.control.DropDown
        VoutDropDownLabel           matlab.ui.control.Label
        VoutDropDown                matlab.ui.control.DropDown
        TypeofdampEditFieldLabel    matlab.ui.control.Label
        TypeofdampEditField         matlab.ui.control.EditField
        startdisplayEditFieldLabel  matlab.ui.control.Label
        startdisplayEditField       matlab.ui.control.EditField
        enddisplayLabel             matlab.ui.control.Label
        enddisplayEditField         matlab.ui.control.EditField
        FrequencySpinnerLabel       matlab.ui.control.Label
        FrequencySpinner            matlab.ui.control.Spinner
        AmplitudeSpinnerLabel       matlab.ui.control.Label
        AmplitudeSpinner            matlab.ui.control.Spinner
        DropDown_6                  matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            circuit = app.CurcuitDropDown.Value;
            r_unit = app.DropDown;
            l_unit = app.DropDown_2;
            c_unit = app.DropDown_3;
            f_unit = app.DropDown_5;
            start_time = app.startdisplayEditField.Value;
            time = app.DropDown_6.Value;
            amplitude = app.AmplitudeSpinner.Value;
            signal = app.signalDropDown.Value;
            out = app.VoutDropDown.Value;
            
            if f_unit.Value == "Hz"
               f = app.FrequencySpinner.Value;
            elseif r_unit.Value == "kHz"
               f = app.FrequencySpinner.Value*10^3;
            end
            
            if r_unit.Value == "mΩ"
               r = app.RSpinner.Value*10^-3;
            elseif r_unit.Value == "kΩ"
               r = app.RSpinner.Value*10^3;
               
            elseif r_unit.Value == "Ω"
               r = app.RSpinner.Value;
            end
            
            if l_unit.Value == "mH"
               l = app.LSpinner.Value*10^-3;
            elseif l_unit.Value == "uH"
               l = app.LSpinner.Value*10^-6;
            elseif l_unit.Value == "nH"
               l = app.LSpinner.Value*10^-9;
            elseif l_unit.Value == "H"
               l = app.LSpinner.Value;
            end
            
            if c_unit.Value == "F"
               c = app.CSpinner.Value;
            elseif c_unit.Value == "uF"
               c = app.CSpinner.Value*10^-6;
            elseif c_unit.Value == "nF"
               c = app.CSpinner.Value*10^-9;
            elseif c_unit.Value == "pF"
               c = app.CSpinner.Value*10^-12;
            end
           
             if circuit == "series"
                damp = r/(2*l);
                w = 1/sqrt(l*c);
                if out == "R"
                   G = tf([0 r 0],[r l 1/(c)])
                   [mag,phase] = bode(G);
                   plot(app.UIAxes2,squeeze(mag));
                   plot(app.UIAxes2_4,squeeze(phase)); 
                   check_case(app,damp,w);
                   series_out(app);
                elseif out == "L"
                   G = tf([0 l 0],[r l 1/(c)])
                   [mag,phase] = bode(G);
                   plot(app.UIAxes2,squeeze(mag));
                   plot(app.UIAxes2_4,squeeze(phase)); 
                   check_case(app,damp,w);
                   series_out(app);
                elseif out == "C"
                   G = tf([0 1 0],[r*c l*c 1])
                   [mag,phase] = bode(G);
                   plot(app.UIAxes2,squeeze(mag));
                   plot(app.UIAxes2_4,squeeze(phase)); 
                   check_case(app,damp,w);
                   series_out(app);
                end

            elseif circuit == "parallel"
                damp = 1/(2*r*c);
                w = 1/sqrt(l*c);
                G = tf([1/(r*c) 0],[1 1/(r*c) 1/(l*c)])
                [mag,phase] = bode(G);
                plot(app.UIAxes2,squeeze(mag));
                plot(app.UIAxes2_4,squeeze(phase));
                check_case(app,damp,w);
            end
            
        function check_case(app,damp,w)
            if w < damp
                app.TypeofdampEditField.Value = "overdamped";
                
            elseif w == damp
                app.TypeofdampEditField.Value = "critical damped";
            elseif w > damp
                app.TypeofdampEditField.Value = "underdamped";
            end
        end

       function series_out(app)
            Xc=1/(2*pi*f*c);
            Xl=2*pi*f*l;
            Rt = sqrt(r^2+(Xc-Xl)^2);
            omega = 2*pi*f;
            if signal == "sine"
                t=(0:1/(f*100):0.1);
                Im=amplitude/Rt;
                i1 = Im*sin(omega*t);
                v = amplitude*sin(omega*t);
                
                if out == "R"
                    Vr = Im*r*sin((omega*t));
                    plot(app.UIAxes2_2,Vr);
                    i = v/Rt;
                    plot(app.UIAxes2_3,i);
                elseif out == "L"
                    Vl = Im*Xl*sin((omega*t)+(pi/2));
                    plot(app.UIAxes2_2,Vl);
                    i = v/Rt;
                    plot(app.UIAxes2_3,i);
                elseif out == "C"
                     Vc= Im*Xc*sin((omega*t)-(pi/2))
                    plot(app.UIAxes2_2,Vc);
                    i = 0;
                    plot(app.UIAxes2_3,i);
                end
            elseif signal == "step"
                I(s) = (1/s)/(r+s*l+1/(s*c))       
                i(a)=simplify(ilaplace(I(s),s,a))
                if out == "R"
                    v = i(a)*r
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                elseif out == "L"
                    v = i(a)*l
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                elseif out == "C"
                    v = i(a)*c
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                end
            else
                I(s) = (1/s^2)/(r+s*l+1/(s*c))       
                i(a)=simplify(ilaplace(I(s),s,a))
                if out == "R"
                    v = i(a)*r
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                elseif out == "L"
                    v = i(a)*l
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                elseif out == "C"
                    v = i(a)*c
                    fplot(app.UIAxes2_2,v);
                    fplot(app.UIAxes2_3,i)
                end
             end
            
            end
        end
  

        end

        % Value changed function: CurcuitDropDown
        function CurcuitDropDownValueChanged(app, event)
            value = app.CurcuitDropDown.Value;
            value = app.CurcuitDropDown.Value;
           if value == "parallel"
                im = app.Image;
                im.ImageSource = 'parallel.png';
           else
                im = app.Image;
                im.ImageSource = 'series.png';
         
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Frequency(Hz)')
            ylabel(app.UIAxes2, 'Magnitude(dB)')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, 'Frequency(Hz)')
            ylabel(app.UIAxes2_4, 'Phase(deg)')
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Ω', 'mΩ', 'kΩ', ''};
            app.DropDown.Value = 'Ω';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'mH', 'uH', 'nH', 'H'};
            app.DropDown_2.Value = 'H';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'uF', 'F', 'nF', 'pF'};
            app.DropDown_3.Value = 'F';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'kHz', 'Hz'};
            app.DropDown_5.Value = 'Hz';
            app.VALabel = uilabel(app.UIFigure);
            app.VALabel.Text = '[V][A]';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'series.png';
            app.BodeDiagramLabel = uilabel(app.UIFigure);
            app.BodeDiagramLabel.Text = 'Bode Diagram';
            app.OutputWaveformVoltageCurrentLabel = uilabel(app.UIFigure);
            app.OutputWaveformVoltageCurrentLabel.Text = 'Output Waveform(Voltage,Current)';
            app.VoltageLabel = uilabel(app.UIFigure);
            app.VoltageLabel.Text = 'Voltage';
            app.CurrentLabel = uilabel(app.UIFigure);
            app.CurrentLabel.Text = 'Current';
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.LSpinnerLabel = uilabel(app.UIFigure);
            app.LSpinnerLabel.Text = 'L';
            app.LSpinner = uispinner(app.UIFigure);
            app.CSpinnerLabel = uilabel(app.UIFigure);
            app.CSpinnerLabel.Text = 'C';
            app.CSpinner = uispinner(app.UIFigure);
            app.signalDropDownLabel = uilabel(app.UIFigure);
            app.signalDropDownLabel.Text = 'signal';
            app.signalDropDown = uidropdown(app.UIFigure);
            app.signalDropDown.Items = {'ramp', 'sine', 'unit step'};
            app.signalDropDown.Value = 'sine';
            app.CurcuitDropDownLabel = uilabel(app.UIFigure);
            app.CurcuitDropDownLabel.Text = 'Curcuit';
            app.CurcuitDropDown = uidropdown(app.UIFigure);
            app.CurcuitDropDown.Items = {'series', 'parallel', ''};
            app.CurcuitDropDown.ValueChangedFcn = createCallbackFcn(app, @CurcuitDropDownValueChanged, true);
            app.CurcuitDropDown.Value = 'series';
            app.VoutDropDownLabel = uilabel(app.UIFigure);
            app.VoutDropDownLabel.Text = 'Vout';
            app.VoutDropDown = uidropdown(app.UIFigure);
            app.VoutDropDown.Items = {'R', 'L', 'C'};
            app.VoutDropDown.Value = 'R';
            app.TypeofdampEditFieldLabel = uilabel(app.UIFigure);
            app.TypeofdampEditFieldLabel.Text = 'Type of damp';
            app.TypeofdampEditField = uieditfield(app.UIFigure, 'text');
            app.startdisplayEditFieldLabel = uilabel(app.UIFigure);
            app.startdisplayEditFieldLabel.Text = 'start display';
            app.startdisplayEditField = uieditfield(app.UIFigure, 'text');
            app.enddisplayLabel = uilabel(app.UIFigure);
            app.enddisplayLabel.Text = 'end display';
            app.enddisplayEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencySpinnerLabel = uilabel(app.UIFigure);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.UIFigure);
            app.AmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude ';
            app.AmplitudeSpinner = uispinner(app.UIFigure);
            app.DropDown_6 = uidropdown(app.UIFigure);
            app.DropDown_6.Items = {'s', 'ms', 'us'};
            app.DropDown_6.Value = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_rlc1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end